classdef appnumber2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LightCurtainDemoButton    matlab.ui.control.Button
        AutomaticWateringButton   matlab.ui.control.Button
        MoistureSensorButton      matlab.ui.control.Button
        VisualServoingButton      matlab.ui.control.Button
        CollisionAvoidanceButton  matlab.ui.control.Button
        ZSlider                   matlab.ui.control.Slider
        ZSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        XSliderLabel              matlab.ui.control.Label
        RESUMEButton              matlab.ui.control.Button
        STOPButton                matlab.ui.control.Button
        waterplant5Button         matlab.ui.control.Button
        waterplant4Button         matlab.ui.control.Button
        waterplant3Button         matlab.ui.control.Button
        waterplant2Button         matlab.ui.control.Button
        waterplant1Button         matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        waterPlant1 = 0;
        waterPlant5 = 0;
        stopWatering = 0;
        scriptFinished = 0;
        dobot

        x = 0;
        y = 0;
        z = 0;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.dobot = myDobotRail;
        end

        % Button pushed function: waterplant1Button
        function waterplant1ButtonPushed(app, event)
            water_plant_2(app.dobot, 1);
        end

        % Callback function
        function waterplant5ButtonPushed(app, event)
            water_plant_2(app.dobot, 5);
        end

        % Callback function
        function StopWateringButtonPushed(app, event)
            app.stopWatering = 1;
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.z = changingValue;
            display('Z Slider');


             display(changingValue);
             %app.ur5.model.getpos();

             %app.ur5.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
             ikcon_z = transl([app.x,app.y,app.z]);
             dobotIkcon = app.dobot.model.ikcon(ikcon_z);

             app.dobot.model.animate(dobotIkcon);
            
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
              app.y = changingValue;
            display('Y Slider');


             display(changingValue);
             %app.ur5.model.getpos();

             %app.ur5.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
             ikcon_y = transl([app.x,app.y,app.z]);
             dobotIkcon = app.dobot.model.ikcon(ikcon_y);

             app.dobot.model.animate(dobotIkcon);
            
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.x = changingValue;
            display('X Slider');


             display(changingValue);
             %app.ur5.model.getpos();

             %app.ur5.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
             ikcon_x = transl([app.x,app.y,app.z]);
             dobotIkcon = app.dobot.model.ikcon(ikcon_x);

             app.dobot.model.animate(dobotIkcon);
            
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
                pwStatus = true
while(pwStatus)
check = input('please enter password to continue operation  ');

if check == 41013
    display('yay');
    pwStatus = false
    
else
    while(check ~= 41013)
   display('please try again');
   check = input('pw:  ');
   if check == 41013
   display('please press enter');
       display('exiting');
       pwStatus = false
   end
    end
end
end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
              input('please RESUME to continue operation');
        end

        % Button pushed function: waterplant2Button
        function waterplant2ButtonPushed(app, event)
            water_plant_2(app.dobot, 2);
        end

        % Button pushed function: waterplant3Button
        function waterplant3ButtonPushed(app, event)
            water_plant_2(app.dobot, 3);
        end

        % Button pushed function: waterplant4Button
        function waterplant4ButtonPushed(app, event)
            water_plant_2(app.dobot, 4);
        end

        % Button pushed function: CollisionAvoidanceButton
        function CollisionAvoidanceButtonPushed(app, event)
            disp('Collision demo loading...please wait :)')
            testCollision;
            input("press enter");

        end

        % Button pushed function: VisualServoingButton
        function VisualServoingButtonPushed(app, event)
            myVisualServoing(app.dobot);
            myEnv;                          % Calls myEnv function which plots all objects in environment
            app.dobot = myDobotRail();
        end

        % Button pushed function: MoistureSensorButton
        function MoistureSensorButtonPushed(app, event)
            myWaterPlantMoisture(app.dobot);
        end

        % Button pushed function: AutomaticWateringButton
        function AutomaticWateringButtonPushed(app, event)
            auto_mode(app.dobot);
        end

        % Button pushed function: LightCurtainDemoButton
        function LightCurtainDemoButtonPushed(app, event)
            disp('--> Starting Demo of Light Curtain')
            myLightCurtainDemo;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.waterplant1Button = uibutton(app.UIFigure, 'push');
            app.waterplant1Button.ButtonPushedFcn = createCallbackFcn(app, @waterplant1ButtonPushed, true);
            app.waterplant1Button.Text = 'water plant 1';
            app.waterplant2Button = uibutton(app.UIFigure, 'push');
            app.waterplant2Button.ButtonPushedFcn = createCallbackFcn(app, @waterplant2ButtonPushed, true);
            app.waterplant2Button.Text = 'water plant 2';
            app.waterplant3Button = uibutton(app.UIFigure, 'push');
            app.waterplant3Button.ButtonPushedFcn = createCallbackFcn(app, @waterplant3ButtonPushed, true);
            app.waterplant3Button.Text = 'water plant 3';
            app.waterplant4Button = uibutton(app.UIFigure, 'push');
            app.waterplant4Button.ButtonPushedFcn = createCallbackFcn(app, @waterplant4ButtonPushed, true);
            app.waterplant4Button.Text = 'water plant 4';
            app.waterplant5Button = uibutton(app.UIFigure, 'push');
            app.waterplant5Button.Text = 'water plant 5';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-5 5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-5 5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.CollisionAvoidanceButton = uibutton(app.UIFigure, 'push');
            app.CollisionAvoidanceButton.ButtonPushedFcn = createCallbackFcn(app, @CollisionAvoidanceButtonPushed, true);
            app.CollisionAvoidanceButton.Text = 'Collision Avoidance';
            app.VisualServoingButton = uibutton(app.UIFigure, 'push');
            app.VisualServoingButton.ButtonPushedFcn = createCallbackFcn(app, @VisualServoingButtonPushed, true);
            app.VisualServoingButton.Text = 'Visual Servoing';
            app.MoistureSensorButton = uibutton(app.UIFigure, 'push');
            app.MoistureSensorButton.ButtonPushedFcn = createCallbackFcn(app, @MoistureSensorButtonPushed, true);
            app.MoistureSensorButton.Text = 'Moisture Sensor';
            app.AutomaticWateringButton = uibutton(app.UIFigure, 'push');
            app.AutomaticWateringButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticWateringButtonPushed, true);
            app.AutomaticWateringButton.Text = {'Automatic'; 'Watering'};
            app.LightCurtainDemoButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainDemoButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainDemoButtonPushed, true);
            app.LightCurtainDemoButton.Text = {'Light Curtain'; 'Demo'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appnumber2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end